classdef Manual_Ext_Cal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageFolderEditField       matlab.ui.control.EditField
        ImageFolderEditFieldLabel  matlab.ui.control.Label
        RadarFolderEditField       matlab.ui.control.EditField
        RadarFolderEditFieldLabel  matlab.ui.control.Label
        EnterButton                matlab.ui.control.Button
        ZSlider                    matlab.ui.control.Slider
        ZSliderLabel               matlab.ui.control.Label
        YSlider                    matlab.ui.control.Slider
        YSliderLabel               matlab.ui.control.Label
        XSlider                    matlab.ui.control.Slider
        XSliderLabel               matlab.ui.control.Label
        YawSlider                  matlab.ui.control.Slider
        YawSliderLabel             matlab.ui.control.Label
        PitchSlider                matlab.ui.control.Slider
        PitchSliderLabel           matlab.ui.control.Label
        RollSlider                 matlab.ui.control.Slider
        RollSliderLabel            matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)

            X = app.XSlider.Value;
            Y = app.YSlider.Value;
            Z = app.ZSlider.Value;
            r = app.RollSlider.Value;
            p = app.PitchSlider.Value;
            y = app.YawSlider.Value;
            load("camera_intrinsic.mat");
            foc = camera_intrinsic(1);
            u0 = camera_intrinsic(5);
            v0 = camera_intrinsic(4);
            dx = camera_intrinsic(2);
            dy = camera_intrinsic(3);

            opt_ext = [y,p,r,X,Y,Z,foc,u0,v0];
            t = trvec2tform(opt_ext(4:6));  %translation matrix
            r = rotm2tform(eul2rotm(opt_ext(1:3)));   %rotation matrix
            ci = [opt_ext(7) 0 0 0; 0 opt_ext(7) 0 0; 0 0 1 0];   %camera to image transformation matrix
            ip = [1/dx 0 opt_ext(8); 0 1/dy opt_ext(9); 0 0 1];     %image to pixel transformation matrix

            PCD = getpcddat(app.RadarFolderEditField.Value);
            xyz = PCD(1:3,:);
            xyz = vertcat(xyz, ones(1,size(PCD,2)));
            %xyz(3,:) = xyz(3,:);
            range = PCD(2,:);

            Yrc = t*r*xyz;
            Yrc = Yrc./(Yrc(3,:));
            Yrc = ip*ci*Yrc;

            %myFolder = 'C:\Users\20225507\Documents\MATLAB\Calib_Images';
            myFolder = app.ImageFolderEditField.Value;
            DataFiles = dir(fullfile(myFolder, '*.jpg'));
            str = fullfile(myFolder, DataFiles(1).name);
            I = imread(str);
            h = size(I,1);
            p = Yrc;
            p(3,:) = [];
            p(2,:) = h - p(2,:);
            p = p';

            J = insertText(I,p,range);
            J = insertMarker(J, p, 'x-mark', 'Color', 'red', 'Size', 8);
            imshow(J,'Parent',app.UIAxes)
            B = opt_ext(1:6);
            save ext_par_manual.mat B;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Calibration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-3.14 3.14];
            app.RollSlider.MajorTicks = [-3.14 -1.57 0 1.57 3.14];
            app.RollSlider.Value = 1.57;
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-3.14 3.14];
            app.PitchSlider.MajorTicks = [-3.14 -1.57 0 1.57 3.14];
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.YawSlider.Limits = [-3.14 3.14];
            app.YawSlider.MajorTicks = [-3.14 -1.57 0 1.57 3.14];
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.MajorTicks = [-5 -3 -1 0 1 3 5];
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.RadarFolderEditFieldLabel = uilabel(app.UIFigure);
            app.RadarFolderEditFieldLabel.Text = 'Radar Folder';
            app.RadarFolderEditField = uieditfield(app.UIFigure, 'text');
            app.RadarFolderEditField.Value = 'C:\Users\20225507\Documents\MATLAB\Radar';
            app.ImageFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFolderEditFieldLabel.Text = 'Image Folder';
            app.ImageFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFolderEditField.Value = 'C:\Users\20225507\Documents\MATLAB\Calib_Images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_Ext_Cal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end